classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        BrowseforimageButton     matlab.ui.control.Button
        CreateedgesButton        matlab.ui.control.Button
        ChooseimageDropDown      matlab.ui.control.DropDown
        LevelDropDown            matlab.ui.control.DropDown
        LevelDropDownLabel       matlab.ui.control.Label
        MessageTextAreaLabel     matlab.ui.control.Label
        messageviewTextArea      matlab.ui.control.TextArea
        UpdatedetailButton       matlab.ui.control.Button
        ReconstructButton        matlab.ui.control.Button
        EvaluateButton           matlab.ui.control.Button
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        UpdateButton             matlab.ui.control.Button
        CONTROLLabel             matlab.ui.control.Label
        ChooseimageLabel         matlab.ui.control.Label
        Image_2                  matlab.ui.control.UIAxes
        Image                    matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        originalImage       % Lưu ảnh gốc (ma trận double)
        maxLevel            % Mức phân giải tối đa có thể
        LL                  % Lưu ma trận xấp xỉ LL ở level cuối cùng
        detailArr           % Cell array lưu các ma trận HL, LH, HH ở từng level
        detailArrAfter      % Cell array lưu các chi tiết sau khi cắt ngưỡng
        reconstructedImage  % Ảnh sau khi tái tạo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChooseimageDropDown
        function ChooseimageDropDownValueChanged(app, event)
            value = app.ChooseimageDropDown.Value;
            try     
                img = imread(value);
                if size(img, 3) == 3
                    img = rgb2gray(img); 
                end

                [r, c] = size(img);
                app.maxLevel = min(6, floor(log2(min(r, c))));
                
                rNew = r - mod(r, 2^app.maxLevel);
                cNew = c - mod(c, 2^app.maxLevel);

                imageNew = img(1:rNew, 1:cNew);

                app.originalImage = double(imageNew);
                app.LevelDropDown.Items = string(1:app.maxLevel);
                app.messageviewTextArea.Value = {'Đã tải ảnh thành công. Hãy chọn Level và nhấn Update.'};

                imshow(uint8(app.originalImage), 'Parent', app.Image);
                imshow(uint8(app.originalImage), 'Parent', app.Image_2);

            catch ME
                app.messageviewTextArea.Value = {['Lỗi: ', ME.message]};
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            try
                level = str2double(app.LevelDropDown.Value);

                [app.LL, app.detailArr] = haar_decompose(app.originalImage, level);

                curImage = app.LL; 

                for i = level:-1:1
                    HL = app.detailArr{i, 1};
                    LH = app.detailArr{i, 2};
                    HH = app.detailArr{i, 3};
                    
                    topHalf = [curImage, HL];
                    bottomHalf = [LH, HH];
                    curImage = [topHalf; bottomHalf];
                end

                imshow(uint8(curImage), 'Parent', app.Image_2);

                app.messageviewTextArea.Value = sprintf('Đã phân tích MRA thành công với Level = %d.\nMa trận đã lưu vào bộ nhớ!', level);

            catch ME
                app.messageviewTextArea.Value = {['Lỗi Update: ', ME.message]};
            end
        end

        % Button pushed function: UpdatedetailButton
        function UpdatedetailButtonPushed(app, event)
            try
                tau = app.ThresholdEditField.Value;

                app.detailArrAfter = app.detailArr;

                level = str2double(app.LevelDropDown.Value);

                totalCoEff = 0;
                zeroCoEff = 0;

                for i = 1:level
                    for j = 1:3
                        coEff = app.detailArrAfter{i, j};

                        totalCoEff = totalCoEff + numel(coEff);


                        coEff(abs(coEff) < tau) = 0;
  
                        zeroCoEff = zeroCoEff + sum(coEff(:) == 0);


                        app.detailArrAfter{i, j} = coEff;
                    end
                end

                sparsity = (zeroCoEff / totalCoEff) * 100;

                app.messageviewTextArea.Value = sprintf('Đã áp dụng Threshold = %d.\nTỷ lệ triệt tiêu dữ liệu (Sparsity) = %.2f%%', tau, sparsity);

            catch ME
                app.messageviewTextArea.Value = {['Lỗi Update Detail: ', ME.message]};
            end
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            try

                level = str2double(app.LevelDropDown.Value);

                if isempty(app.detailArrAfter)
                    details = app.detailArr;
                else
                    details = app.detailArrAfter;
                end

                imageReconstructed = haar_reconstruct(app.LL, details, level);

                imageReconstructed = max(min(imageReconstructed, 255), 0);


                app.reconstructedImage = imageReconstructed;
                imshow(uint8(app.reconstructedImage), 'Parent', app.Image_2);

                app.messageviewTextArea.Value = {'Đã tái tạo (Reconstruct) ảnh thành công!'};

            catch ME
                app.messageviewTextArea.Value = {['Lỗi Reconstruct: ', ME.message]};
            end
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            try
                if isempty(app.originalImage) || isempty(app.reconstructedImage)
                    app.messageviewTextArea.Value = {'Lỗi: Vui lòng thực hiện Update và Reconstruct trước khi Evaluate.'};
                    return;
                end

                I_goc = app.originalImage;
                I_recon = app.reconstructedImage;

                [r, c] = size(I_recon); 

                mse_val = mean((I_goc(:) - I_recon(:)).^2);

                if mse_val == 0
                    psnr_val = Inf; 
                else
                    psnr_val = 10 * log10((255^2) / mse_val);
                end


                if isempty(app.detailArrAfter)
                    details = app.detailArr;
                else
                    details = app.detailArrAfter;
                end

                zero_count = 0;
                total_count = 0;
                level = str2double(app.LevelDropDown.Value);
                for i = 1:level
                    for j = 1:3
                        coeff = details{i, j};
                        zero_count = zero_count + sum(coeff(:) == 0);
                        total_count = total_count + numel(coeff);
                    end
                end
                sparsity = (zero_count / total_count) * 100;

                report = sprintf(['BÁO CÁO ĐÁNH GIÁ (Level %d):\n- MSE (Sai số): %.4f\n' ...
                    '- PSNR (Chất lượng): %.2f dB\n' ...
                    '- Sparsity (Tỷ lệ triệt tiêu): %.2f%%'], level, mse_val, psnr_val, sparsity);
                app.messageviewTextArea.Value = {report};

            catch ME
                app.messageviewTextArea.Value = {['Lỗi Evaluate: ', ME.message]};
            end
        end

        % Button pushed function: CreateedgesButton
        function CreateedgesButtonPushed(app, event)
            try
                if isempty(app.detailArr)
                    app.messageviewTextArea.Value = {'Lỗi: Vui lòng nhấn Update để phân tích ảnh trước!'};
                    return;
                end

                level = str2double(app.LevelDropDown.Value);

                if isempty(app.detailArrAfter)
                    details = app.detailArr;
                else
                    details = app.detailArrAfter;
                end

                zero_LL = zeros(size(app.LL));

                edgeImage = haar_reconstruct(zero_LL, details, level);

                edgeImage = abs(edgeImage);

                edgeImage = edgeImage * 2; 
                edgeImage = max(min(edgeImage, 255), 0); 

                imshow(uint8(edgeImage), 'Parent', app.Image_2);

                app.messageviewTextArea.Value = {'Đã trích xuất Cạnh (Edges) thành công bằng cách triệt tiêu dải tần thấp (LL)!'};

            catch ME
                app.messageviewTextArea.Value = {['Lỗi Create Edges: ', ME.message]};
            end
        end

        % Button pushed function: BrowseforimageButton
        function BrowseforimageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif', 'Image Files (*.jpg, *.png, *.bmp)'}, 'Chọn một bức ảnh từ máy tính');

            if isequal(file, 0)
                return; 
            end

            try
                fullFileName = fullfile(path, file);

                img = imread(fullFileName);

                if size(img, 3) == 3
                    img = rgb2gray(img); 
                end

                [r, c] = size(img);
                app.maxLevel = min(6, floor(log2(min(r, c))));

                rNew = r - mod(r, 2^app.maxLevel);
                cNew = c - mod(c, 2^app.maxLevel);
                imageNew = img(1:rNew, 1:cNew);

                app.originalImage = double(imageNew);
                app.LevelDropDown.Items = string(1:app.maxLevel);

                app.messageviewTextArea.Value = {sprintf('Đã tải ảnh "%s" thành công. Hãy chọn Level và nhấn Update.', file)};
                imshow(uint8(app.originalImage), 'Parent', app.Image);
                imshow(uint8(app.originalImage), 'Parent', app.Image_2);

            catch ME
                app.messageviewTextArea.Value = {['Lỗi đọc file: ', ME.message]};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiaxes(app.UIFigure);
            title(app.Image, 'Original image')
            app.Image_2 = uiaxes(app.UIFigure);
            title(app.Image_2, 'Processed image')
            app.Panel = uipanel(app.UIFigure);
            app.ChooseimageLabel = uilabel(app.Panel);
            app.ChooseimageLabel.Text = 'Choose image: ';
            app.CONTROLLabel = uilabel(app.Panel);
            app.CONTROLLabel.Text = 'CONTROL';
            app.UpdateButton = uibutton(app.Panel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ThresholdEditFieldLabel = uilabel(app.Panel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Panel, 'numeric');
            app.EvaluateButton = uibutton(app.Panel, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.ReconstructButton = uibutton(app.Panel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.UpdatedetailButton = uibutton(app.Panel, 'push');
            app.UpdatedetailButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatedetailButtonPushed, true);
            app.UpdatedetailButton.Text = 'Update detail';
            app.messageviewTextArea = uitextarea(app.Panel);
            app.messageviewTextArea.Value = {'Image reconstructed, sparsity = ...'};
            app.MessageTextAreaLabel = uilabel(app.Panel);
            app.MessageTextAreaLabel.Text = 'Message ';
            app.LevelDropDownLabel = uilabel(app.Panel);
            app.LevelDropDownLabel.Text = 'Level';
            app.LevelDropDown = uidropdown(app.Panel);
            app.LevelDropDown.Items = {'1', '2', '3', '4'};
            app.LevelDropDown.Value = '1';
            app.ChooseimageDropDown = uidropdown(app.Panel);
            app.ChooseimageDropDown.Items = {'dog', 'cat', 'chicken', 'duck', 'letters', 'Kim Ji Won', 'noisy'};
            app.ChooseimageDropDown.ItemsData = {'dog.jpg', 'cat.jpg', 'chicken.jpg', 'duck.jpg', 'letters.jpg', 'KimJiWon.jpg', 'noisy.jpg'};
            app.ChooseimageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseimageDropDownValueChanged, true);
            app.ChooseimageDropDown.Value = 'dog.jpg';
            app.CreateedgesButton = uibutton(app.Panel, 'push');
            app.CreateedgesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateedgesButtonPushed, true);
            app.CreateedgesButton.Text = 'Create edges';
            app.BrowseforimageButton = uibutton(app.Panel, 'push');
            app.BrowseforimageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforimageButtonPushed, true);
            app.BrowseforimageButton.Text = 'Browse for image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end